classdef OSIVEIMS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        nmLabel                        matlab.ui.control.Label
        Wavelength                     matlab.ui.control.Label
        Irradiancia                    matlab.ui.control.Label
        mWmm2Label                     matlab.ui.control.Label
        ActualirradiancemeasuredLabel  matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        Label                          matlab.ui.control.Label
        RescanButton                   matlab.ui.control.Button
        ConnectButton                  matlab.ui.control.Button
        COMPortDropDown                matlab.ui.control.DropDown
        COMPortDropDownLabel           matlab.ui.control.Label
        JavierMonrealTrigoLabel        matlab.ui.control.Label
        JoseManuelTerrsHaroLabel       matlab.ui.control.Label
        OSIVEIMSLabel                  matlab.ui.control.Label
        IrradianceMeasurementSystemLabel  matlab.ui.control.Label
        OptochemogeneticStimulationforInVitroExperimentationLabel  matlab.ui.control.Label
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        COM = '';
        PORT = '';
        openedCOM = 0;
        inputMessage = '';
        ADCs = [];
        index = 0;
        mWmm2 = [];
        SensibilityValues = [];
        ADCSensibilities = [];
    end

    methods (Access = private)
    
        function inputMessageProcess(app, src, event)
            while(src.BytesAvailable>0)
                app.inputMessage = char(fread(src,1));
                if(strcmp(app.inputMessage,'D'))
                    while(src.BytesAvailable<39)
                    end
                    app.inputMessage(2:40) = char(fread(src,39));
                    if(app.index<100)
                        app.index = app.index + 1;
                    else
                        app.mWmm2(1) = [];
                        app.ADCs(1,:) = [];
                    end
                    if(app.index>1)
                        app.ADCs(app.index,1) = str2num(app.inputMessage(6:9));
                        app.ADCs(app.index,2) = str2num(app.inputMessage(11:14));
                        app.ADCs(app.index,3) = str2num(app.inputMessage(19:19));
                        app.ADCs(app.index,4) = str2num(app.inputMessage(21:24));
                        app.ADCs(app.index,5) = str2num(app.inputMessage(26:29));
                        app.ADCs(app.index,6) = str2num(app.inputMessage(31:34));
                        app.ADCs(app.index,7) = str2num(app.inputMessage(36:39));
                        [minValue,closestIndex] = min(abs(app.ADCs(app.index,:)-512));
%                         app.mWmm2(app.index) = ((app.ADCs(app.index,closestIndex)/1024.0*27.4/3^(closestIndex-1))/app.SensibilityValues(round(app.Slider.Value)-400+1)...
%                             -0.008/app.SensibilityValues(round(app.Slider.Value)-400+1))...
%                             /10; % Lens of 10-12º, fotoemitter @ ~22 mm, sensible area is virtually x10
                        app.mWmm2(app.index) = app.ADCs(app.index,closestIndex)/1024.0*5000.0/app.ADCSensibilities(closestIndex)/app.SensibilityValues(round(app.Slider.Value)-400+1);
                        hp = plot(app.UIAxes,app.ADCs);
                        set(hp, {'color'}, {[1 0 0]; [1 0 1]; [1 0 1]; [1 0 1]; [0 0 1]; [0 0 1]; [0 0 1]});
                        set(hp, {'LineStyle'}, {'-'; '--'; ':'; '-'; '--'; ':'; '-'});
                        le = legend(app.UIAxes,'ADCx1','ADCx3','ADCx9','ADCx27','ADCx81','ADCx243','ADCx749');
                        le.Location = 'northwest';
                        hp2 = plot(app.UIAxes2,app.mWmm2,'-o');
                        set(hp2,'color',[1 0 1])
                        strIrradiance = num2str(app.mWmm2(app.index),'%05.3f');
                        if(length(strIrradiance)<6)
                            strIrradiance = strcat('0',strIrradiance);
                        end
                        app.Irradiancia.Text = strcat({' '},strIrradiance,{' '});
                    end
                end
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            instrreset
            if(~isempty(seriallist))
                app.COMPortDropDown.Items = seriallist;
                COMs = app.COMPortDropDown.Items;
            else
                app.COMPortDropDown.Items = {'[none]'};
                COMs = [];
            end
            if(~isempty(COMs))
                if(iscell(COMs))
                    app.COM = app.COMPortDropDown.Value;
                else
                    app.COM = COMs;
                end
            end
            clearvars COMs % Clear variable
            app.openedCOM = 0;
            
            xq = 400:1100;
            x = [400, 450,  550, 650, 750, 850, 925,950, 1050,1100];
            v = [0.04,0.115,0.33,0.57,0.78,0.95,1,  0.95,0.35,0.13];
            app.SensibilityValues = interp1(x,v,xq,'spline');
            
            app.ADCSensibilities(1) = 234*7.5; % mV/(mW/mm2)
            for i = 2:7
                app.ADCSensibilities(i) = app.ADCSensibilities(i-1)*3;
            end
              
            app.Wavelength.Text = strcat({' '},num2str(round(app.Slider.Value),'%04d'),{' '});
            xq = 400:1100;
            plot(app.UIAxes3,xq,app.SensibilityValues);
            hold(app.UIAxes3,'on')
            plot(app.UIAxes3,round(app.Slider.Value),app.SensibilityValues(round(app.Slider.Value)-400+1),'bo');
            hold(app.UIAxes3,'off')
            axis(app.UIAxes3,[400 1100 0 1.1])
        end

        % Value changed function: COMPortDropDown
        function COMPortDropDownValueChanged(app, event)
            ActivePorts = instrfind; % Read active ports
            if isempty(ActivePorts)==0 % Check if there are active ports
                if ~app.openedCOM
                    fclose(ActivePorts); % Close active ports
                    delete(ActivePorts) % Erase variable
                    clear ActivePorts % Destroy variable
                end
            end
            
            app.COM = app.COMPortDropDown.Value;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if(~app.openedCOM)
                % Config serial port
                if(strcmp(app.COM,'[none]')<=0)
                    app.PORT=serial(app.COM); % Generate a serial object linked to COM              
                    set(app.PORT,'BaudRate',9600); 
                    set(app.PORT,'DataBits',8); 
                    set(app.PORT,'Parity','none'); 
                    set(app.PORT,'StopBits',1); 
                    set(app.PORT,'FlowControl','none'); % RTS and DTS manually controlled
                    set(app.PORT,'DataTerminalReady','off'); 
                    set(app.PORT,'RequestToSend','on'); 
                    app.PORT.BytesAvailableFcnCount = 20;  % Trigger of reception event when 1 byte received
                    app.PORT.BytesAvailableFcnMode = 'byte'; % Trigger for number of bytes and not for enders
                    app.PORT.BytesAvailableFcn = {@app.inputMessageProcess};
                    fopen(app.PORT); % Opens port
                    app.openedCOM = 1;
                    app.ConnectButton.FontColor = [0 0.7 0];
                else
                    %errorSound(); 
                    warndlg('No COM port detected or chosen.');
                end
            else
                fclose(app.PORT); % Opens port
                delete(app.PORT)
                clear PORT
                app.openedCOM = 0;
                app.ConnectButton.FontColor = [0 0 0];
            end
        end

        % Button pushed function: RescanButton
        function RescanButtonPushed(app, event)
            try
                port = app.PORT.Port;
            catch
                port = [];
            end
            if(isempty(port))
                if(~app.openedCOM)
                    if(~isempty(seriallist))
                        app.COMPortDropDown.Items = seriallist;
                    else
                        app.COMPortDropDown.Items = '[none]';
                    end
                    app.COM = app.COMPortDropDown.Value;
                end
            else
                %errorSound(); 
                warndlg('First, click on green button CONNECT to disconnect.'); 
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if(app.openedCOM == 1)
                fclose(app.PORT);
                delete(app.PORT)
                clear PORT
                app.openedCOM = 0;
            end
            delete(app)
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.Wavelength.Text = strcat({' '},num2str(round(app.Slider.Value),'%04d'),{' '});
            xq = 400:1100;
            plot(app.UIAxes3,xq,app.SensibilityValues);
            hold(app.UIAxes3,'on')
            plot(app.UIAxes3,round(app.Slider.Value),app.SensibilityValues(round(app.Slider.Value)-400+1),'bo');
            hold(app.UIAxes3,'off')
            axis(app.UIAxes3,[400 1100 0 1.1])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Real time 7 ADC plot')
            xlabel(app.UIAxes, 'Measurement index')
            ylabel(app.UIAxes, 'ADC Value')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Irradiance in real time')
            xlabel(app.UIAxes2, 'Measurement index')
            ylabel(app.UIAxes2, 'Irradiance (mW/mm^2)')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Sensibility curve')
            xlabel(app.UIAxes3, 'Wavelength (nm)')
            ylabel(app.UIAxes3, 'Unitary sensibility')
            app.OptochemogeneticStimulationforInVitroExperimentationLabel = uilabel(app.UIFigure);
            app.OptochemogeneticStimulationforInVitroExperimentationLabel.Text = 'Optochemogenetic Stimulation for In-Vitro Experimentation';
            app.IrradianceMeasurementSystemLabel = uilabel(app.UIFigure);
            app.IrradianceMeasurementSystemLabel.Text = 'Irradiance Measurement System';
            app.OSIVEIMSLabel = uilabel(app.UIFigure);
            app.OSIVEIMSLabel.Text = 'OSIVE IMS';
            app.JoseManuelTerrsHaroLabel = uilabel(app.UIFigure);
            app.JoseManuelTerrsHaroLabel.Text = 'Jose Manuel Terrés Haro';
            app.JavierMonrealTrigoLabel = uilabel(app.UIFigure);
            app.JavierMonrealTrigoLabel.Text = 'Javier Monreal Trigo';
            app.COMPortDropDownLabel = uilabel(app.UIFigure);
            app.COMPortDropDownLabel.Text = 'COM Port:';
            app.COMPortDropDown = uidropdown(app.UIFigure);
            app.COMPortDropDown.Items = {'[none]'};
            app.COMPortDropDown.ValueChangedFcn = createCallbackFcn(app, @COMPortDropDownValueChanged, true);
            app.COMPortDropDown.Value = '[none]';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.RescanButton = uibutton(app.UIFigure, 'push');
            app.RescanButton.ButtonPushedFcn = createCallbackFcn(app, @RescanButtonPushed, true);
            app.RescanButton.Text = 'Rescan';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '© 2021';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [400 1100];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 650;
            app.ActualirradiancemeasuredLabel = uilabel(app.UIFigure);
            app.ActualirradiancemeasuredLabel.Text = 'Actual irradiance measured:';
            app.mWmm2Label = uilabel(app.UIFigure);
            app.mWmm2Label.Text = 'mW/mm^2';
            app.Irradiancia = uilabel(app.UIFigure);
            app.Irradiancia.Text = ' 00.000 ';
            app.Wavelength = uilabel(app.UIFigure);
            app.Wavelength.Text = ' 0000 ';
            app.nmLabel = uilabel(app.UIFigure);
            app.nmLabel.Text = 'nm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OSIVEIMS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end